classdef SNR_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        SNRHorizontalLabel  matlab.ui.control.Label
        SNRVerticalLabel    matlab.ui.control.Label
        csvName             matlab.ui.control.Label
        wavName             matlab.ui.control.Label
        CalculateSNRButton  matlab.ui.control.Button
        NextButton          matlab.ui.control.Button
        BackButton          matlab.ui.control.Button
        specPlot            matlab.ui.control.UIAxes
        overlayPlot         matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        wav_directory = uigetdir(pwd, "Select folder containing wav files.")
        csv_directory = uigetdir(pwd, "Select folder containing csv files.")
        matchesDirectory = uigetdir(pwd, "Select directory that will store file matches and snr.");

        matchNo = 0;
        noOfMatches = 0;
    end
    
    methods (Access = private)
        
        % This function plots the graphs for the spectrogram, and another 
        % of the spectrogram with overlays.
        % This is called whenever next or previous whistle buttons are
        % pressed.
        function plotGraphs(app)

            % Getting the macthed csv and file names, which are stored in a
            % csv file.
            file_name = 'Match_Results.csv';
            match_file = fullfile(app.matchesDirectory, file_name);
            match_table = readtable(match_file,"ReadVariableNames", true, "Delimiter", ",");
            app.noOfMatches = height(match_table);

            if (app.matchNo > app.noOfMatches)
                % Next whistle was pressed, and matchNo is bigger than the
                % actual number of matches in the csv file.
                msgbox("No more whistles to display.")
            else
                % Get current row based on what matchNo is.
                row = match_table(app.matchNo,:);
                % Get file paths of the wav and csv file specified in the
                % row.
                wavPath = fullfile(app.wav_directory, strcat(row.("WAV"), ".wav"));
                csvPath = fullfile(app.csv_directory, strcat("pc_", strcat(row.("CSV"), ".csv")));

                % Setting the two labels on the frame to be the names of
                % the two files.
                app.csvName.Text = row.("WAV");
                drawnow
                app.wavName.Text = strcat("pc_", strcat(row.('CSV'), ".csv"));
                drawnow
    
                if (contains(row.("CSV"), "No matches"))
                    % If the wav file had no matches (because it couldn't
                    % be traced in PAMGuard), go onto the next row and try
                    % to plot that row.
                    app.matchNo = app.matchNo + 1;
                    plotGraphs(app)
                else
                    % Plot spectrogram onto graph specified for showing
                    % just the spectrogram.
                    [s, f, t, ~, ~, ~] = getSpectrogramOfWav(wavPath);
                    imagesc(app.specPlot, t, flipud(f), rot90(log(abs(s'))));
                    set(app.specPlot, 'YDir', 'normal')
    
                    % Plot contour overlay

                    % This plots the spectrogram.
                    imagesc(app.overlayPlot, t, flipud(f), rot90(log(abs(s'))));
                    set(app.overlayPlot, 'YDir', 'normal')

                    % Keep the spectrogram on, and any other plottings.
                    hold(app.overlayPlot, "on")

                    % Getting the time into POSIX standard, so that the
                    % contour can be plotted properly onto the spectrogram.
                    time = extractBetween(row.("WAV"), 1, 15);
                    T = datetime(time,'InputFormat','yyyyMMdd_HHmmss');
                    time_init = posixtime(T);


                    % Getting the frequency and time columns for use as y
                    % and x, respectively.
                    contourTable = readtable(csvPath, "ReadVariableNames",true);
                    frequency = contourTable.("PeakFrequency_Hz_");
                    time_ms = (contourTable.("Time_ms_") - time_init*1000) / 1000;
                    
                    % Plot contour onto the spectrogram.
                    plot(app.overlayPlot, time_ms, frequency);

                    % Plot box overlay onto the spectrogram - get corners from computeSNR.
                    [SNR_NoiseAcrossFreq, SNR_NoiseAcrossTime, min_time, max_time, min_frequency, max_frequency] = computeSNR(csvPath, wavPath);

                    app.SNRHorizontalLabel.Text = strcat ("SNR Noise Across Time = ", string(SNR_NoiseAcrossTime));
                    drawnow
                    app.SNRVerticalLabel.Text = strcat ("SNR Noise Across Frequency = ", string(SNR_NoiseAcrossFreq));
                    drawnow

                    % Left line of box.
                    plot (app.overlayPlot, [min_time, min_time], [min_frequency, max_frequency])

                    % Right line of box.
                    plot (app.overlayPlot, [max_time, max_time], [min_frequency, max_frequency])

                    % Bottom line of box.
                    plot (app.overlayPlot, [min_time, max_time], [min_frequency, min_frequency])

                    % Top line of box.
                    plot (app.overlayPlot, [min_time, max_time], [max_frequency, max_frequency])

                    hold(app.overlayPlot, "off")
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Find the matches between the files in the provided csv and
            % wav directories.
            findMatches(app.wav_directory, app.csv_directory, app.matchesDirectory);
            % Make snr results file.
            file_name = 'SNR_Results.csv';
            snr_file = fullfile(app.matchesDirectory, file_name);
            writelines("Contour,Clip,SNR_Noise_Across_Time, SNR_Noise_Across_Frequency", snr_file, "WriteMode","overwrite");
        end

        % Button pushed function: NextButton
        function nextWhistle(app, event)
            if (app.matchNo > app.noOfMatches)
                msgbox("Out of bounds whistle.")
                % Plot current overlay and whistle (the last 
                % whistle/contour pairing).
                plotGraphs(app)
            else
                % Increment to next match.
                app.matchNo = app.matchNo + 1;
                % Plot overlay and whistle
                plotGraphs(app)
            end
        end

        % Button pushed function: BackButton
        function previousWhistle(app, event)
            if (app.matchNo < 1)
                msgbox("Out of bounds whistle.")
                % Plot current overlay and whistle.
                plotGraphs(app)
            else
                app.matchNo = app.matchNo - 1;
                % Plot overlay and whistle (the first 
                % whistle/contour pairing).
                plotGraphs(app)
            end
        end

        % Button pushed function: CalculateSNRButton
        function writeSNR(app, event)
            % Getting the correct row from the csv file of matched csv 
            % and wav files..
            file_name = 'Match_Results.csv';
            match_file = fullfile(app.matchesDirectory, file_name);
            match_table = readtable(match_file,"ReadVariableNames", true);
            row = match_table(app.matchNo,:);

            % Get file paths of the file names in the row..
            wavPath = fullfile(app.wav_directory, strcat(row.("WAV"), ".wav"));
            csvPath = fullfile(app.csv_directory, strcat("pc_", strcat(row.("CSV"), ".csv")));

            % Get the snr of the csv/wav file by using computeSNR.
            [snr_freq, snr_time, ~, ~, ~, ~] = computeSNR(csvPath, wavPath);

            % Specify snr results file, and write wav name and it's value to it.
            file_name = 'SNR_Results.csv';
            snr_file = fullfile(app.matchesDirectory, file_name);
            cName = strcat (strcat("pc_", strcat(row.("CSV"), ".csv")), ",");
            wName = strcat(strcat(row.("WAV"), ".wav"), ",");
            fileNames = strcat(cName, wName);
            snrValues = strcat ( strcat( string(snr_freq), ","), string(snr_time) );
            writelines(strcat(cName, strcat(fileNames, snrValues)), snr_file, "WriteMode","append");

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.overlayPlot = uiaxes(app.UIFigure);
            title(app.overlayPlot, 'Overlay')
            xlabel(app.overlayPlot, 'X')
            ylabel(app.overlayPlot, 'Y')
            zlabel(app.overlayPlot, 'Z')
            app.specPlot = uiaxes(app.UIFigure);
            title(app.specPlot, 'Clip')
            xlabel(app.specPlot, 'X')
            ylabel(app.specPlot, 'Y')
            zlabel(app.specPlot, 'Z')
            app.BackButton = uibutton(app.UIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @previousWhistle, true);
            app.BackButton.Text = 'Back';
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @nextWhistle, true);
            app.NextButton.Text = 'Next';
            app.CalculateSNRButton = uibutton(app.UIFigure, 'push');
            app.CalculateSNRButton.ButtonPushedFcn = createCallbackFcn(app, @writeSNR, true);
            app.CalculateSNRButton.Text = 'Calculate SNR';
            app.wavName = uilabel(app.UIFigure);
            app.wavName.Text = 'WAV File';
            app.csvName = uilabel(app.UIFigure);
            app.csvName.Text = 'CSV File';
            app.SNRVerticalLabel = uilabel(app.UIFigure);
            app.SNRVerticalLabel.Text = 'SNR Vertical = ';
            app.SNRHorizontalLabel = uilabel(app.UIFigure);
            app.SNRHorizontalLabel.Text = 'SNR Horizontal = ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SNR_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end